classdef ANLPLG3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ANLPluginUIFigure           matlab.ui.Figure
    GridLayout                  matlab.ui.container.GridLayout
    Panel                       matlab.ui.container.Panel
    GridLayout5                 matlab.ui.container.GridLayout
    ResidueoffsetSpinner        matlab.ui.control.Spinner
    ResidueoffsetSpinnerLabel   matlab.ui.control.Label
    ResiduefactorSpinner        matlab.ui.control.Spinner
    ResiduefactorSpinnerLabel   matlab.ui.control.Label
    ResetRangeButton            matlab.ui.control.Button
    ComputeButton               matlab.ui.control.Button
    LastPointSpinner            matlab.ui.control.Spinner
    LastPointSpinnerLabel_3     matlab.ui.control.Label
    FirstPointSpinner           matlab.ui.control.Spinner
    FirstPointSpinnerLabel_3    matlab.ui.control.Label
    TabGroup                    matlab.ui.container.TabGroup
    SingleExponentTab           matlab.ui.container.Tab
    GridLayout3                 matlab.ui.container.GridLayout
    SE_TimeOffsetSpinner        matlab.ui.control.Spinner
    TimeOffsetusSpinnerLabel    matlab.ui.control.Label
    SingleExponentwithIRFTab    matlab.ui.container.Tab
    GridLayout6                 matlab.ui.container.GridLayout
    IRFguessSpinner             matlab.ui.control.Spinner
    IRFguessSpinnerLabel        matlab.ui.control.Label
    DoubleExponentTab           matlab.ui.container.Tab
    GridLayout4                 matlab.ui.container.GridLayout
    DE_TimeOffsetSpinner        matlab.ui.control.Spinner
    TimeOffsetusSpinnerLabel_2  matlab.ui.control.Label
    LightDarkTab                matlab.ui.container.Tab
    GridLayout2                 matlab.ui.container.GridLayout
    SkewSpinner                 matlab.ui.control.Spinner
    SkewLabel                   matlab.ui.control.Label
    PhaseSpinner                matlab.ui.control.Spinner
    PhaseLabel                  matlab.ui.control.Label
    Tab5                        matlab.ui.container.Tab
    tOutput                     matlab.ui.control.TextArea
  end


  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer % Description
  end

  methods (Access = public)
    %-----------------------------------------------------------------
    function results = FileUpdate(app)
      results = '';
      if app.NoneButton == 1
      elseif app.LightDarkButton.Value == 1

        [Data, src] = LD(app);
        if size(src.y, 1) > 1
          out = {};
          out{1} = src;
          out{1}.y = Data;
          out{1}.ax.Color = 'r';

          app.KazanViewer.SetData('output', out);
          results = 'OK';
        end
      elseif app.ExponentButton.Value == 1
        [Data, src] = LD(app);

        if size(src.y, 1) > 1
          out = {};
          out{1} = src;
          out{1}.y = Data;
          out{1}.ax.Color = 'r';

          FirstPoint = app.SE_FirstPointSpinner.Value;
          TT = app.KazanViewer.out{1}.ax.x(FirstPoint:end); TT=TT(:);
          y = real(Data(FirstPoint:end)); y = y(:);

          fit_recovery = @(x,t) x(1) - x(3)*exp(-t*x(2));

          fit_fun = @(x) sqrt(sum((y - fit_recovery(x,TT)).^2));
          opt = optimset('Display','off');
          [est_TT, err] = fminsearch(fit_fun, [max(y),1/(max(TT)/5),(max(y)-min(y))], opt);
          ShowMessage(app, sprintf('A-B*exp(-t/T)\nresidual=%5.4g\nA = %5.3g  B = %5.3g  T = %6.4f us',...
            err, est_TT(1), est_TT(3), 1/est_TT(2)*1e6))

          out{2} = src;
          out{2}.ax.x = TT;
          out{2}.ax.y = 0;
          out{2}.ax.xlabel = src.ax.ylabel;
          out{2}.y = fit_recovery(est_TT, TT);
          out{2}.ax.Color = 'g';

          app.KazanViewer.SetData('output', out);
        end
      elseif app.TwoExponentsButton.Value == 1
        [Data, src] = LD(app);

        if size(src.y, 1) > 1
          hand = guidata(app.Master);
          app.KazanViewer.out = {};
          app.KazanViewer.out{1} = src;
          app.KazanViewer.out{1}.y = Data;
          app.KazanViewer.out{1}.ax.Color = 'r';

          FirstPoint = app.SE_FirstPointSpinner.Value;
          TT = app.KazanViewer.out{1}.ax.x(FirstPoint:end); TT=TT(:);
          y = real(Data(FirstPoint:end)); y = y(:);

          fit_recovery = @(x,t) (x(1) - x(3)*exp(-t*x(2))) .* exp(-t*x(4)) + x(5);

          fit_fun = @(x) sqrt(sum((y - fit_recovery(x,TT)).^2));
          opt = optimset('Display','off');
          [est_TT, err] = fminsearch(fit_fun, [(max(y)-min(y)),1/(max(TT)/5),(max(y)-min(y)), 1/(max(TT)/5), max(y)], opt);
          residual = err;
          ShowMessage(app, sprintf('(A - Bexp(-t/T1)) exp(-t/T2) + C\nresidual=%5.4g\nA = %5.3g  B = %5.3g  T1: %6.4f us\nC = %5.3g  T2 = %6.4f us',...
            residual, est_TT(1), est_TT(3), 1/est_TT(2)*1e6,est_TT(5),1/est_TT(4)*1e6))

          app.KazanViewer.out{2} = src;
          app.KazanViewer.out{2}.ax.x = TT;
          app.KazanViewer.out{2}.ax.y = 0;
          app.KazanViewer.out{2}.ax.xlabel = src.ax.ylabel;
          app.KazanViewer.out{2}.y = fit_recovery(est_TT, TT);
          app.KazanViewer.out{2}.ax.Color = 'g';

          [~,name] = fileparts(get(app.Master, 'FileName'));
          eval([name '(''SetDataSource'', 2, hand)']);
        end
      elseif app.FTButton.Value == 1
        [Data, src] = LD(app);
        hand = guidata(app.Master);

        Data = Data - mean(Data);
        % zero filling
        Data(length(Data)*4) = 0;

        xx = src.ax.x;
        dx = mean(diff(xx));
        n = length(Data);
        app.KazanViewer.out = {};
        app.KazanViewer.out{1} = src;
        app.KazanViewer.out{1}.ax.x = 1/dx/n*linspace(-n/2+1, n/2, n)*1E-6;
        app.KazanViewer.out{1}.ax.xlabel = 'Frequency, MHz';
        app.KazanViewer.out{1}.ax.y = 0;
        app.KazanViewer.out{1}.y = fftshift(fft(Data));
        app.KazanViewer.out{1}.ax.Color = 'r';

        [~,xidx] = max(abs(app.KazanViewer.out{1}.y));
        [~,xidx2] = max(real(app.KazanViewer.out{1}.y));
        ShowMessage(app, sprintf('Max(abs): %f MHz\nMax(real): %f MHz\n', app.KazanViewer.out{1}.ax.x(xidx), app.KazanViewer.out{1}.ax.x(xidx2)))

        %                 [~,LOCS,W,P] = findpeaks(real(app.KazanViewer.out{1}.y));
        %                 maxP = max(P); LOCS = LOCS(P > maxP / 5);
        %                 ShowMessage(app, sprintf('Peaks: %f MHz\n', app.KazanViewer.out{1}.ax.x(LOCS)));

        [~,name] = fileparts(get(app.Master, 'FileName'));
        eval([name '(''SetDataSource'', 2, hand)']);
      end
    end

    function results = InitApp(app, hh)
      app.Master = hh;
    end
  end
  %-----------------------------------------------------------------

  methods (Access = private)
    %-----------------------------------------------------------------
    function [Data, src] = LD(app)
      src = app.KazanViewer.GetData('source');
      Phase = app.PhaseSpinner.Value;
      Skew  = app.SkewSpinner.Value;

      % LD experiment
      k = size(src.y);
      isLD = k(1)==2 && k(2) > 2;

      if isLD
        disp('Light-Dark processing engaged size(y,1)==2.');
        Data = repmat(src.y(1,:),size(src.y, 1),1) - src.y;
        Data = Data(2:end,:);
        for ii=1:size(Data,1)
          Data(ii,:) = real(Data(ii,:)) + 1i*imag(Data(ii,:))*Skew;
          Data(ii,:) = Data(ii,:) * exp(-1i*Phase/180*pi);
        end
        Data = Data';

        src.ax.x = src.ax.y;
        src.ax.xlabel = src.ax.ylabel;
        src.ax.y = 0;
      else
        disp('Phase only processing engaged size(y,1)~=2.');
        Data = src.y;
        Data = real(Data) + 1i * imag(Data) * Skew;
        Data = Data * exp(-1i*Phase/180*pi);
      end
    end

    %-----------------------------------------------------------------
    function results = ShowMessage(app, message)
      results = [];
      app.tOutput.Value = message;
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
    end

    % Callback function: not associated with a component
    function PhaseSpinnerValueChanged(app, event)
      FileUpdate(app);
    end

    % Callback function: not associated with a component
    function SkewSpinnerValueChanged(app, event)
      FileUpdate(app);
    end

    % Callback function: not associated with a component
    function ButtonGroupSelectionChanged(app, event)
      FileUpdate(app);
    end

    % Callback function: not associated with a component
    function FirstPointSpinnerValueChanged(app, event)
      FileUpdate(app);
    end

    % Close request function: ANLPluginUIFigure
    function ANLPluginUIFigureCloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)

    end

    % Button pushed function: ComputeButton
    function ComputeButtonPushed(app, event)
      src = app.KazanViewer.GetData('source');
      
      % all in usec and real only
      t = src.ax.x * 1e6;
      y = real(src.y);

      bl = mean(y(1:5));
      
      out = {};
      out{1} = src;
      out{end}.title = 'Source';

      % Plot residuals
      resi_multi = app.ResiduefactorSpinner.Value; % multiplier for residual plotting
      resi_off = app.ResidueoffsetSpinner.Value; % offset for residual plotting
      
      switch app.TabGroup.SelectedTab.Title
        case 'Single Exponent'
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          %% Mono-Exponential Decay Fit
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = app.SE_TimeOffsetSpinner.Value; % time offset correction in microseconds
          t_fitrange = t_fitrange-t0;
          
          % Define the exponential decay function
          exp_decay = @(params, t) params(1) * exp(-t_fitrange/params(2));

          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay(params, t_fitrange)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          initial_guess = [max(y_data), max(y_data)/10];

          % Perform the fitting using fminsearch
          options = optimset('Display', 'off', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-8, 'TolFun', 1e-8);
          [fit_params, fval] = fminsearch(obj_fun, initial_guess, options);

          % Generate fitted data for plotting
          y_fit = exp_decay(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          ShowMessage(app, sprintf('T1 = %.2fus   Amp=%f\nfval=%f', fit_params(2), fit_params(1),fval/fnorm));
        case 'Single Exponent with IRF'
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          %% Mono-Exponential Decay Fit with IRF
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = app.SE_TimeOffsetSpinner.Value; % time offset correction in microseconds
          t_fitrange = t_fitrange-t0;

          % Define the exponential decay function
          exp_decay = @(params, t) params(1) * expIRF(t-abs(params(4)), abs(params(2)), abs(params(3))); % amplitude, time vector, time corr, lifetime tau, sigma IRF

          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay(params, t)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          IRFguess = app.IRFguessSpinner.Value;
          initial_guess = [max(y_data), max(y_data)/10, IRFguess, 0];

          % Perform the fitting using fminsearch
          options = optimset('Display', 'off', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-8, 'TolFun', 1e-8);
          [fit_params, fval] = fminsearch(obj_fun, initial_guess, options);

          % Generate fitted data for plotting
          y_fit = exp_decay(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          ShowMessage(app, sprintf('T1 = %.2fus   Amp=%f\nIRF=%fus\nfval=%f', fit_params(2), fit_params(1),...
            fit_params(3), fval/fnorm));
        case 'Double Exponent'
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          %% Bi-Exponential Decay Fit
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = app.DE_TimeOffsetSpinner.Value; % time offset correction in microseconds
          t_fitrange = t_fitrange-t0;
          
          % Define the exponential decay function
          exp_decay_all = @(params, t) params(1) * exp(-t_fitrange/params(2)) + params(3) * exp(-t_fitrange/params(4));
          
          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay_all(params, t_fitrange)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          initial_guess = [max(y_data)/2, max(y_data)/10, max(y_data)/2, max(y_data)/4];

          % Perform the fitting using fminsearch
          options = optimset('Display', 'off', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-8, 'TolFun', 1e-8);
          [fit_params, fval] = fminsearch(obj_fun, initial_guess, options);

          % Generate fitted data for plotting
          y_fit = exp_decay_all(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          ShowMessage(app, sprintf('T1 = %.2fus Amp=%f\nT2 = %.2fus Amp=%f\nfval=%f', fit_params(2), fit_params(1), ...
            fit_params(4), fit_params(3), fval/fnorm));
      end

      app.KazanViewer.SetData('output', out);

    end

    % Button pushed function: ResetRangeButton
    function ResetRangeButtonPushed(app, event)
      src = app.KazanViewer.GetData('source');
      app.FirstPointSpinner.Value = min(src.ax.x) * 1e6;
      app.LastPointSpinner.Value = max(src.ax.x) * 1e6;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.ANLPluginUIFigure = uifigure('Visible', 'off');
      app.ANLPluginUIFigure.Name = 'ANL Plugin';
      app.ANLPluginUIFigure.CloseRequestFcn = createCallbackFcn(app, @ANLPluginUIFigureCloseRequest, true);
      app.GridLayout = uigridlayout(app.ANLPluginUIFigure);
      app.tOutput = uitextarea(app.GridLayout);
      app.TabGroup = uitabgroup(app.GridLayout);
      app.SingleExponentTab = uitab(app.TabGroup);
      app.SingleExponentTab.Title = 'Single Exponent';
      app.GridLayout3 = uigridlayout(app.SingleExponentTab);
      app.TimeOffsetusSpinnerLabel = uilabel(app.GridLayout3);
      app.TimeOffsetusSpinnerLabel.Text = 'Time Offset [us]';
      app.SE_TimeOffsetSpinner = uispinner(app.GridLayout3);
      app.SE_TimeOffsetSpinner.Limits = [0 Inf];
      app.SE_TimeOffsetSpinner.ValueDisplayFormat = '%11.4gus';
      app.SingleExponentwithIRFTab = uitab(app.TabGroup);
      app.SingleExponentwithIRFTab.Title = 'Single Exponent with IRF';
      app.GridLayout6 = uigridlayout(app.SingleExponentwithIRFTab);
      app.IRFguessSpinnerLabel = uilabel(app.GridLayout6);
      app.IRFguessSpinnerLabel.Text = 'IRF guess';
      app.IRFguessSpinner = uispinner(app.GridLayout6);
      app.IRFguessSpinner.Limits = [0 Inf];
      app.IRFguessSpinner.ValueDisplayFormat = '%11.4gus';
      app.DoubleExponentTab = uitab(app.TabGroup);
      app.DoubleExponentTab.Title = 'Double Exponent';
      app.GridLayout4 = uigridlayout(app.DoubleExponentTab);
      app.TimeOffsetusSpinnerLabel_2 = uilabel(app.GridLayout4);
      app.TimeOffsetusSpinnerLabel_2.Text = 'Time Offset [us]';
      app.DE_TimeOffsetSpinner = uispinner(app.GridLayout4);
      app.DE_TimeOffsetSpinner.Limits = [0 Inf];
      app.DE_TimeOffsetSpinner.ValueDisplayFormat = '%11.4gus';
      app.LightDarkTab = uitab(app.TabGroup);
      app.LightDarkTab.Title = 'Light-Dark';
      app.GridLayout2 = uigridlayout(app.LightDarkTab);
      app.PhaseLabel = uilabel(app.GridLayout2);
      app.PhaseLabel.Text = 'Phase';
      app.PhaseSpinner = uispinner(app.GridLayout2);
      app.SkewLabel = uilabel(app.GridLayout2);
      app.SkewLabel.Text = 'Skew';
      app.SkewSpinner = uispinner(app.GridLayout2);
      app.Tab5 = uitab(app.TabGroup);
      app.Tab5.Title = 'Tab5';
      app.Panel = uipanel(app.GridLayout);
      app.GridLayout5 = uigridlayout(app.Panel);
      app.FirstPointSpinnerLabel_3 = uilabel(app.GridLayout5);
      app.FirstPointSpinnerLabel_3.Text = 'First Point';
      app.FirstPointSpinner = uispinner(app.GridLayout5);
      app.FirstPointSpinner.Limits = [0 Inf];
      app.FirstPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.LastPointSpinnerLabel_3 = uilabel(app.GridLayout5);
      app.LastPointSpinnerLabel_3.Text = 'Last Point';
      app.LastPointSpinner = uispinner(app.GridLayout5);
      app.LastPointSpinner.Limits = [0 Inf];
      app.LastPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.ComputeButton = uibutton(app.GridLayout5, 'push');
      app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
      app.ComputeButton.Text = 'Compute';
      app.ResetRangeButton = uibutton(app.GridLayout5, 'push');
      app.ResetRangeButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRangeButtonPushed, true);
      app.ResetRangeButton.Text = 'Reset Range';
      app.ResiduefactorSpinnerLabel = uilabel(app.GridLayout5);
      app.ResiduefactorSpinnerLabel.Text = 'Residue factor';
      app.ResiduefactorSpinner = uispinner(app.GridLayout5);
      app.ResiduefactorSpinner.Step = 0.1;
      app.ResiduefactorSpinner.Limits = [1 50];
      app.ResiduefactorSpinner.Value = 3;
      app.ResidueoffsetSpinnerLabel = uilabel(app.GridLayout5);
      app.ResidueoffsetSpinnerLabel.Text = 'Residue offset';
      app.ResidueoffsetSpinner = uispinner(app.GridLayout5);
      app.ResidueoffsetSpinner.Step = 0.1;
      app.ResidueoffsetSpinner.Value = -0.5;
      % Show the figure after all components are created
      app.ANLPluginUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ANLPLG3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ANLPluginUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ANLPluginUIFigure)
    end
  end
end