classdef Noise3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1         matlab.ui.Figure
    GridLayout      matlab.ui.container.GridLayout
    ddNoiseOptions  matlab.ui.control.DropDown
    ddFitOptions    matlab.ui.control.DropDown
    ClearRefButton  matlab.ui.control.Button
    tOutput         matlab.ui.control.Label
    pbSetReference  matlab.ui.control.Button
    pbCalculate     matlab.ui.control.Button
  end

  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer  % Description
    Reference    % Description
  end
  
  methods (Access = public)
    
    function results = FileUpdate(app)
      pbCalculate_Callback(app, []);
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
    end

    % Button pushed function: pbSetReference
    function pbSetReference_Callback(app, event)
      src = app.KazanViewer.GetData('source');
      app.Reference = src;
      app.tOutput.Text = 'Reference is added';
    end

    % Button pushed function: pbCalculate
    function pbCalculate_Callback(app, event)
      noise = app.KazanViewer.GetData('source');

      isref = ~isempty(app.Reference) && isequal(size(app.Reference.y),size(noise.y));
      signalfitoptions = app.ddFitOptions.Value;
      noisefitoptions = app.ddNoiseOptions.Value;

      if isref
        signal = app.Reference;
        noise.y = noise.y - signal.y;
      else
        signal = noise;
      end

      % signal processing options
      switch signalfitoptions
        case 'no_fit' % no signal modifications
        case 'sinus_fit'
          % Define the objective function for SSE (sum of squared errors)
          fitfunc = @(p, x) p(1)*sin(p(2)*x + p(3)) + p(4);
          objectiveFunc = @(params,x,y) sum((y - fitfunc(params, x)).^2);
          xx = (1:size(signal.y,1))';

          % Initial guess for the parameters [amplitude, frequency, phase shift, offset]
          initialGuess = [max(max(real(signal.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
          initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,2)));
          initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,2)));

          initialGuessQ = [max(max(imag(signal.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
          initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,2)));
          initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,2)));

          for ii=1:size(noise.y,2)
            % Use fminsearch to find the best-fit parameters
            bestParams = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,ii)));
            bestParamsQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,ii)));

            signal.y(:,ii) = signal.y(:,ii) - fitfunc(bestParams, xx) - 1i*fitfunc(bestParamsQ, xx);
          end
      end

      % signal processing options
      switch noisefitoptions
        case 'sinus_fit'
          % Define the objective function for SSE (sum of squared errors)
          fitfunc = @(p, x) p(1)*sin(p(2)*x + p(3)) + p(4);
          objectiveFunc = @(params,x,y) sum((y - fitfunc(params, x)).^2);
          xx = (1:size(noise.y,1))';

          % Initial guess for the parameters [amplitude, frequency, phase shift, offset]
          initialGuess = [max(max(real(noise.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
          initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(noise.y(:,2)));
          initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(noise.y(:,2)));

          initialGuessQ = [max(max(imag(noise.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
          initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(noise.y(:,2)));
          initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(noise.y(:,2)));

          for ii=1:size(noise.y,2)
            % Use fminsearch to find the best-fit parameters
            bestParams = fminsearch(objectiveFunc, initialGuess, [], xx, real(noise.y(:,ii)));
            bestParamsQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(noise.y(:,ii)));

            noise.y(:,ii) = noise.y(:,ii) - fitfunc(bestParams, xx) - 1i*fitfunc(bestParamsQ, xx);
          end
        case 'minus_trace2'
          for ii=1:size(noise.y,2)
            noise.y(:,ii) = noise.y(:,ii) - noise.y(:,2);
          end
        case 'filter_x5'
          for ii=1:size(noise.y,2)
            t = fftshift(fft(noise.y(:,ii)));
            n = size(t,1) /2;
            cut_off_idx = 200;
            t([1:(n - cut_off_idx), (n + cut_off_idx) : end]) = 0;
            noise.y(:,ii) =  noise.y(:,ii) - ifft(fftshift(t));
          end
      end

      maxsignal = max(real(signal.y), [], 1) - min(real(signal.y), [], 1);
      noise_std = std(real(noise.y));
      snr = maxsignal./noise_std;


      text_message = sprintf('signal_mV=%.5f\n', maxsignal*1000);
      text_message = [text_message, newline, sprintf('noise_uV=%.5f\n', 1e6*noise_std)];
      text_message = [text_message, newline, sprintf('snr=%.2f\n', snr)];

      % if isfit
      %   signal = app.Reference;
      %
      %   % Define the objective function for SSE (sum of squared errors)
      %   fitfunc = @(p, x) p(1)*sin(p(2)*x + p(3)) + p(4);
      %   objectiveFunc = @(params,x,y) sum((y - fitfunc(params, x)).^2);
      %   xx = (1:size(noise.y,1))';
      %
      %   % Initial guess for the parameters [amplitude, frequency, phase shift, offset]
      %   initialGuess = [max(max(real(signal.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
      %   initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,2)));
      %   initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,2)));
      %
      %   initialGuessQ = [max(max(imag(signal.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
      %   initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,2)));
      %   initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,2)));
      %
      %   for ii=1:size(noise.y,2)
      %     % Use fminsearch to find the best-fit parameters
      %     bestParams = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,ii)));
      %     bestParamsQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,ii)));
      %
      %     signal.y(:,ii) = signal.y(:,ii) - fitfunc(bestParams, xx) - 1i*fitfunc(bestParamsQ, xx);
      %   end
      % else
      %   signal = noise;
      % end
      %
      % % realdata = real(src.y);
      % % realdata = realdata - mean(realdata);
      % % n = size(src.y, 1);
      % %
      % % noise = realdata(floor(n/2):end);
      % % signal = datasmooth(realdata,3,'savgol');
      %
      % maxsignal = max(real(signal.y), [], 1) - min(real(signal.y), [], 1);
      % handles.tOutput.String = [sprintf('signal_mV=%.5f\n', maxsignal*1000), ...
      %   newline, sprintf('snr=%.2f\n', maxsignal./std(real(noise.y))), ...
      %   newline, sprintf('noise_uV=%.5f\n', 1e6*std(real(noise.y)))];
      % % fprintf('noiseplugin: max=%f\n', max(signal));
      % % fprintf('noiseplugin: signal/std/2=%f\n', max(signal)/std(noise)/2);
      %
      
      out = {};

      out{end+1} = noise;
      out{end}.title = 'noise';
      out{end}.ax.Color = 'r';

      out{end+1} = signal;
      out{end}.title = 'signal';
      out{end}.ax.Color = 'b';

      app.KazanViewer.SetData('output', out);

      app.tOutput.Text = text_message;

    end

    % Button pushed function: ClearRefButton
    function ClearRefButtonPushed(app, event)
      app.Reference = [];
      app.tOutput.Text = 'Reference is cleared';
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'noiseplugin';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.GridLayout = uigridlayout(app.figure1);
      app.pbCalculate = uibutton(app.GridLayout, 'push');
      app.pbCalculate.ButtonPushedFcn = createCallbackFcn(app, @pbCalculate_Callback, true);
      app.pbCalculate.Tag = 'pushbutton1';
      app.pbCalculate.Text = 'Calculate';
      app.pbSetReference = uibutton(app.GridLayout, 'push');
      app.pbSetReference.ButtonPushedFcn = createCallbackFcn(app, @pbSetReference_Callback, true);
      app.pbSetReference.Tag = 'pbSetReference';
      app.pbSetReference.Text = 'Set Reference';
      app.tOutput = uilabel(app.GridLayout);
      app.tOutput.Tag = 'tOutput';
      app.tOutput.Text = 'Static Text';
      app.ClearRefButton = uibutton(app.GridLayout, 'push');
      app.ClearRefButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRefButtonPushed, true);
      app.ClearRefButton.Text = 'Clear Ref';
      app.ddFitOptions = uidropdown(app.GridLayout);
      app.ddFitOptions.Items = {'No fit', 'Sinus fit'};
      app.ddFitOptions.ItemsData = {'no_fit', 'sinus_fit'};
      app.ddFitOptions.Placeholder = 'No fit';
      app.ddFitOptions.Value = 'no_fit';
      app.ddNoiseOptions = uidropdown(app.GridLayout);
      app.ddNoiseOptions.Items = {'As is / Ref', 'Sinus fit', '- Trace2', 'Filter x5'};
      app.ddNoiseOptions.ItemsData = {'as_is', 'sinus_fit', 'minus_trace2', 'filter_x5'};
      app.ddNoiseOptions.Value = 'as_is';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Noise3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end